classdef dashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ResultsPanel                    matlab.ui.container.Panel
        StatusLabel                     matlab.ui.control.Label
        IterLabel                       matlab.ui.control.Label
        AngleAxes                       matlab.ui.control.UIAxes
        VoltAxes                        matlab.ui.control.UIAxes
        InputDataPanel                  matlab.ui.container.Panel
        RunLoadFlowButton               matlab.ui.control.Button
        MaxIterField                    matlab.ui.control.EditField
        MaximumIterationEditFieldLabel  matlab.ui.control.Label
        TolField                        matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel         matlab.ui.control.Label
        LineTable                       matlab.ui.control.Table
        BusTable                        matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('../Data/');
            addpath('../Core/');
            app.BusTable.Data = bus_data()
            app.BusTable.ColumnEditable = [true true true true true true];
            app.BusTable.Enable= 'on'
            %Linedata
            app.LineTable.Data = line_data();
            app.LineTable.ColumnEditable = true(1,5);
            app.LineTable.Enable = 'on';

        end

        % Button pushed function: RunLoadFlowButton
        function RunButtonPushed(app, event)
            % Read data from tables
            bus = app.BusTable.Data;
            linedata = app.LineTable.Data;

            nbus = size(bus,1);

            % Solver parameters
            tol = app.TolField.Value;
            maxiter = app.MaxIterField.Value;

            % Form Y-bus
            Ybus = ybus(linedata, nbus);

            % Run NR load flow
            [Vm, Va, iter] = NR_loadflow(bus, Ybus);

            % Plot voltage magnitudes
            bar(app.VoltAxes, Vm);
            xlabel(app.VoltAxes,'Bus Number');
            ylabel(app.VoltAxes,'Voltage (p.u.)');

            % Plot voltage angles
            bar(app.AngleAxes, Va);
            xlabel(app.AngleAxes,'Bus Number');
            ylabel(app.AngleAxes,'Angle (deg)');
    
            % Update status
            app.IterLabel.Text = ['Iterations: ', num2str(iter)];
            app.StatusLabel.Text = 'Status: Converged';
            app.StatusLabel.FontColor = [0 0.5 0];   % green
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputDataPanel = uipanel(app.UIFigure);
            app.InputDataPanel.Title = 'Input Data';
            app.BusTable = uitable(app.InputDataPanel);
            app.BusTable.ColumnName = {'BUS'; 'Type'; 'V'; 'Angle'; 'P'; 'Q'};
            app.BusTable.RowName = {};
            app.BusTable.Tag = 'BusTable';
            app.LineTable = uitable(app.InputDataPanel);
            app.LineTable.ColumnName = {'From'; 'To'; 'R'; 'X'; 'B/2'};
            app.LineTable.RowName = {};
            app.LineTable.Tag = 'LineTable';
            app.ToleranceEditFieldLabel = uilabel(app.InputDataPanel);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.TolField = uieditfield(app.InputDataPanel, 'numeric');
            app.TolField.Tag = 'TolField';
            app.TolField.Value = 1e-06;
            app.MaximumIterationEditFieldLabel = uilabel(app.InputDataPanel);
            app.MaximumIterationEditFieldLabel.Text = 'Maximum Iteration';
            app.MaxIterField = uieditfield(app.InputDataPanel, 'text');
            app.MaxIterField.Tag = 'MaxIterField';
            app.MaxIterField.Value = '20';
            app.RunLoadFlowButton = uibutton(app.InputDataPanel, 'push');
            app.RunLoadFlowButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunLoadFlowButton.Tag = 'RunButton';
            app.RunLoadFlowButton.Text = 'Run Load Flow';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.VoltAxes = uiaxes(app.ResultsPanel);
            title(app.VoltAxes, 'Bus Voltage Magnitudes (p.u.)')
            xlabel(app.VoltAxes, 'X')
            ylabel(app.VoltAxes, 'Y')
            zlabel(app.VoltAxes, 'Z')
            app.VoltAxes.Tag = 'VoltAxes';
            app.AngleAxes = uiaxes(app.ResultsPanel);
            title(app.AngleAxes, 'Bus Voltage Angle(p.u.)')
            xlabel(app.AngleAxes, 'X')
            ylabel(app.AngleAxes, 'Y')
            zlabel(app.AngleAxes, 'Z')
            app.AngleAxes.Tag = 'AngleAxes';
            app.IterLabel = uilabel(app.ResultsPanel);
            app.IterLabel.Tag = 'IterLabel';
            app.IterLabel.Text = 'Iterations-';
            app.StatusLabel = uilabel(app.ResultsPanel);
            app.StatusLabel.Tag = 'StatusLabel';
            app.StatusLabel.Text = 'Status: Not Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dashboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end